classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InfoLabel                  matlab.ui.control.Label
        ModeButtonGroup            matlab.ui.container.ButtonGroup
        ContinuousButton           matlab.ui.control.RadioButton
        DiscreteButton             matlab.ui.control.RadioButton
        WarningLabel               matlab.ui.control.Label
        RRValue                    matlab.ui.control.Label
        RRLabel                    matlab.ui.control.Label
        HRValue                    matlab.ui.control.Label
        HRLabel                    matlab.ui.control.Label
        ReadingSwitch              matlab.ui.control.Switch
        InterferometerButtonGroup  matlab.ui.container.ButtonGroup
        SagnacButton               matlab.ui.control.RadioButton
        MZIButton                  matlab.ui.control.RadioButton
        RRFltPlot                  matlab.ui.control.UIAxes
        HRFltPlot                  matlab.ui.control.UIAxes
        RawPlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        counter = 0
        dq
        HR
        RR
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReadingSwitch
        function ReadingSwitchValueChanged(app, event)
            value = app.ReadingSwitch.Value;

            % NI DAQ setting
            app.dq = daq("ni");
            if app.SagnacButton.Value
                addinput(app.dq, "Dev4", "ai0", "Voltage");
            else
                addinput(app.dq, "Dev4", "ai1", "Voltage");
                addinput(app.dq, "Dev4", "ai2", "Voltage");
                addinput(app.dq, "Dev4", "ai3", "Voltage");
            end
            app.dq.Rate = 5000;
            
            if value == "On"
                app.InterferometerButtonGroup.Enable = 'off';
                app.ModeButtonGroup.Enable = 'off';

                clearInterface();

                % NI DAQ background acquisition placeholder
                app.dq.ScansAvailableFcnCount = 1250;
                app.dq.ScansAvailableFcn = @(src,evt) plotDataAvailable(src,evt);

                % Create an empty matrix to store data
                scanData = [];

                if app.DiscreteButton.Value
                    start(app.dq,"Duration",seconds(30));
                    hold(app.RawPlot,"on");
                    while app.dq.Running
                        pause(0.1);
                    end
                    app.ReadingSwitch.Value = "off";
                else
                    start(app.dq,"continuous");
                    hold(app.RawPlot,"on");
                    while app.dq.Running
                        pause(0.1);
                    end
                end
            end

            function plotDataAvailable(src,~)
                [data,timestamps,~] = read(src,src.ScansAvailableFcnCount,"OutputFormat","Matrix");
                p = plot(app.RawPlot,mod(timestamps,30),data);
                p(1).Color = "#0072BD";
                if app.ContinuousButton.Value
                    p(2).Color = "#D95319";
                    p(3).Color = "#EDB120";
                end
                scanData = [scanData; data];

                if app.DiscreteButton.Value
                    % Plot the raw and filtered data into the plot
                    % Show the HR and RR on the interface
                    if app.SagnacButton.Value
                        sagnacPlot(scanData);
                    else
                        mziPlot(scanData);
                    end
                        
                    % Abnormal HR & RR detection
                    abnormalDetect(app.HR, app.RR);
                    
                    % HTTP POST request to update HR and RR in middleware
                    postData(app.HR, app.RR);
                else
                    if mod(length(scanData),50000) == 0
                        app.counter = app.counter + 1;
        
                        % Plot the raw and filtered data into the plot
                        % Show the HR and RR on the interface
                        if app.SagnacButton.Value
                            sagnacPlot(scanData);
                        else
                            mziPlot(scanData);
                        end
                            
                        % Abnormal HR & RR detection
                        abnormalDetect(app.HR, app.RR);
                        
                        % HTTP POST request to update HR and RR in middleware
                        postData(app.HR, app.RR);
                        
                        % Clear the Raw plot
                        if mod(app.counter,3) == 0
                            cla(app.RawPlot);
                        end
                    end
        
                    % Discarding old data
                    if length(scanData) > 150000
                        scanData(1:50000,:) = [];
                    end
                end
            end

            function sagnacPlot(scanData)
                L = length(scanData);
                [app.HR,app.RR,hrFlt,rrFlt] = Sagnac_Extraction(scanData);
                plot(app.HRFltPlot,linspace(0,L/5000,L),hrFlt);
                plot(app.RRFltPlot,linspace(0,L/5000,L),rrFlt);
                app.HRValue.Text = string(app.HR);
                app.RRValue.Text = string(app.RR);
            end

            function mziPlot(scanData)
                L = length(scanData);
                [app.HR,app.RR,hrFlt,rrFlt] = MZI_Extraction(scanData);
                plot(app.HRFltPlot,linspace(0,L/5000,L-1),hrFlt);
                plot(app.RRFltPlot,linspace(0,L/5000,L-1),rrFlt);
                app.HRValue.Text = string(app.HR);
                app.RRValue.Text = string(app.RR);
            end

            function abnormalDetect(HR, RR)
                % Change to red color to alert user is HR is abnormal
                if (HR > 100) || (HR < 45)
                    app.HRValue.FontColor = "r";
                else
                    app.HRValue.FontColor = "k";
                end

                % Change to red color to alert user is RR is abnormal
                if (RR > 25) || (RR < 15)
                    app.RRValue.FontColor = "r";
                else
                    app.RRValue.FontColor = "k";
                end

                % Show warning message if HR or RR is abnormal
                if (HR > 100) || (HR < 45) || (RR > 25) || (RR < 15)
                    app.WarningLabel.Visible = "on";
                else
                    app.WarningLabel.Visible = "off";
                end
            end

            function postData(HR, RR)
                message = matlab.net.http.MessageBody();
                message.Payload = sprintf('{"hr":%d,"rr":%d}',HR,RR);
                request = matlab.net.http.RequestMessage('POST',[matlab.net.http.field.ContentTypeField('application/json'),matlab.net.http.field.AcceptField('application/json')],message);
                request.send('http://192.168.1.3:3000');
            end

            function clearInterface()
                app.HRValue.Text = "-";
                app.RRValue.Text = "-";
                app.HRValue.FontColor = "k";
                app.RRValue.FontColor = "k";
                app.WarningLabel.Visible = "off";
                cla(app.RawPlot);
                cla(app.HRFltPlot);
                cla(app.RRFltPlot);
                app.InterferometerButtonGroup.Enable = 'on';
                app.dq.stop();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FYP HR&RR Extraction';
            app.RawPlot = uiaxes(app.UIFigure);
            title(app.RawPlot, 'Raw BCG Diagram')
            xlabel(app.RawPlot, 'Time (s)')
            ylabel(app.RawPlot, 'Voltage (V)')
            zlabel(app.RawPlot, 'Z')
            app.RawPlot.XLim = [0 30];
            app.HRFltPlot = uiaxes(app.UIFigure);
            title(app.HRFltPlot, 'HR Filtered BCG Diagram')
            xlabel(app.HRFltPlot, 'Time (s)')
            ylabel(app.HRFltPlot, 'Voltage (V)')
            zlabel(app.HRFltPlot, 'Z')
            app.HRFltPlot.XLim = [0 30];
            app.RRFltPlot = uiaxes(app.UIFigure);
            title(app.RRFltPlot, 'RR Filtered BCG Diagram')
            xlabel(app.RRFltPlot, 'Time (s)')
            ylabel(app.RRFltPlot, 'Voltage (V)')
            zlabel(app.RRFltPlot, 'Z')
            app.RRFltPlot.XLim = [0 30];
            app.InterferometerButtonGroup = uibuttongroup(app.UIFigure);
            app.InterferometerButtonGroup.Title = 'Interferometer';
            app.MZIButton = uiradiobutton(app.InterferometerButtonGroup);
            app.MZIButton.Text = 'MZI';
            app.MZIButton.Value = true;
            app.SagnacButton = uiradiobutton(app.InterferometerButtonGroup);
            app.SagnacButton.Text = 'Sagnac';
            app.ReadingSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReadingSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadingSwitchValueChanged, true);
            app.HRLabel = uilabel(app.UIFigure);
            app.HRLabel.Text = 'HR:';
            app.HRValue = uilabel(app.UIFigure);
            app.HRValue.Text = '-';
            app.RRLabel = uilabel(app.UIFigure);
            app.RRLabel.Text = 'RR:';
            app.RRValue = uilabel(app.UIFigure);
            app.RRValue.Text = '-';
            app.WarningLabel = uilabel(app.UIFigure);
            app.WarningLabel.Visible = 'off';
            app.WarningLabel.Text = 'Abnormal detected!';
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.Title = 'Mode';
            app.DiscreteButton = uiradiobutton(app.ModeButtonGroup);
            app.DiscreteButton.Text = 'Discrete ';
            app.DiscreteButton.Value = true;
            app.ContinuousButton = uiradiobutton(app.ModeButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = {'Please do not move your body vigorously when detecting'; 'Data will be updated every 10 seconds'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end